classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        ClearButton            matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        YourNoteTextArea       matlab.ui.control.TextArea
        YourNoteTextAreaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           % Get the cell array of notes from the TextArea component
    notesCellArray = app.YourNoteTextArea.Value;

    % Open a dialog to choose the save location
    [filename, pathname] = uiputfile('*.txt', 'Save Note');

    % Check if the user canceled the dialog
    if filename == 0
        return;
    end

    % Combine the pathname and filename to get the full file path
    fullpath = fullfile(pathname, filename);

    % Open the file for writing
    fid = fopen(fullpath, 'w');
    if fid == -1
        msgbox('Error saving note.', 'Error', 'error');
        return;
    end

    % Iterate through the cell array and write each note to the file
    for i = 1:numel(notesCellArray)
        fprintf(fid, '%s\n', notesCellArray{i});
    end

    % Close the file
    fclose(fid);

    msgbox('Notes saved successfully.', 'Success');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Open a dialog to choose the file to load
    [filename, pathname] = uigetfile('*.txt', 'Load Note');
    
    % Check if the user canceled the dialog
    if filename == 0
        return;
    end
    
    % Combine the pathname and filename to get the full file path
    fullpath = fullfile(pathname, filename);
    
    % Read the note text from the file
    fid = fopen(fullpath, 'r');
    if fid == -1
        msgbox('Error loading note.', 'Error', 'error');
    else
        noteText = fscanf(fid, '%c');
        fclose(fid);
        app.YourNoteTextArea.Value = noteText;
        msgbox('Note loaded successfully.', 'Success');
    end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.YourNoteTextArea.Value = ''; % Clear the text area
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YourNoteTextAreaLabel = uilabel(app.UIFigure);
            app.YourNoteTextAreaLabel.Text = 'Your Note:';
            app.YourNoteTextArea = uitextarea(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'diskette.png');
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'loading.png');
            app.LoadButton.Text = 'Load';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = fullfile(pathToMLAPP, 'bin.png');
            app.ClearButton.Text = 'Clear';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'This app helps you to create a note in text file and also to load notes from a text file.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'It solves the problem of creating new text file everytime and makes it hassle free';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end