classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YourRGBPhotoinBandWLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        SelectRGBPhotoButton      matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectRGBPhotoButton
        function SelectRGBPhotoButtonPushed(app, event)
            % Load the selected image
    [filename, pathname] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.tif'}, 'Select an image');
    if isequal(filename,0)
        return; % User canceled the operation
    end
    fullImagePath = fullfile(pathname, filename);
    rgbImage = imread(fullImagePath);

    % Convert RGB image to grayscale
    grayImage = rgb2gray(rgbImage);

    % Display the grayscale image in the Axes component
    imshow(grayImage, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectRGBPhotoButton = uibutton(app.UIFigure, 'push');
            app.SelectRGBPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRGBPhotoButtonPushed, true);
            app.SelectRGBPhotoButton.Text = 'Select RGB Photo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Description: This app allows you to convert any RGBH Photo in to B and W Photo';
            app.YourRGBPhotoinBandWLabel = uilabel(app.UIFigure);
            app.YourRGBPhotoinBandWLabel.Text = 'Your RGB Photo in B and W';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end